classdef Poker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Button_1      matlab.ui.control.Button
        Button_2      matlab.ui.control.Button
        Button_3      matlab.ui.control.Button
        Button_4      matlab.ui.control.Button
        Button_5      matlab.ui.control.Button
        Button_6      matlab.ui.control.Button
        Button_7      matlab.ui.control.Button
        Button_8      matlab.ui.control.Button
        Button_9      matlab.ui.control.Button
        Button_10     matlab.ui.control.Button
        Button_11     matlab.ui.control.Button
        Button_12     matlab.ui.control.Button
        Button_13     matlab.ui.control.Button
        Button_14     matlab.ui.control.Button
        Button_15     matlab.ui.control.Button
        Button_16     matlab.ui.control.Button
        Button_17     matlab.ui.control.Button
        Button_18     matlab.ui.control.Button
        Button_19     matlab.ui.control.Button
        Button_20     matlab.ui.control.Button
        Button_21     matlab.ui.control.Button
        Button_22     matlab.ui.control.Button
        Button_23     matlab.ui.control.Button
        Button_24     matlab.ui.control.Button
        Button_25     matlab.ui.control.Button
        Button_26     matlab.ui.control.Button
        Button_27     matlab.ui.control.Button
        Button_28     matlab.ui.control.Button
        Button_29     matlab.ui.control.Button
        Button_30     matlab.ui.control.Button
        Button_31     matlab.ui.control.Button
        Button_32     matlab.ui.control.Button
        Button_33     matlab.ui.control.Button
        Button_34     matlab.ui.control.Button
        Button_35     matlab.ui.control.Button
        Button_36     matlab.ui.control.Button
        Button_37     matlab.ui.control.Button
        Button_38     matlab.ui.control.Button
        Button_39     matlab.ui.control.Button
        Button_40     matlab.ui.control.Button
        Button_41     matlab.ui.control.Button
        Button_42     matlab.ui.control.Button
        Button_43     matlab.ui.control.Button
        Button_44     matlab.ui.control.Button
        Button_45     matlab.ui.control.Button
        Button_46     matlab.ui.control.Button
        Button_47     matlab.ui.control.Button
        Button_48     matlab.ui.control.Button
        Button_49     matlab.ui.control.Button
        Button_50     matlab.ui.control.Button
        Button_51     matlab.ui.control.Button
        Button_52     matlab.ui.control.Button
        Button_53     matlab.ui.control.Button
        Button_54     matlab.ui.control.Button
        Button_55     matlab.ui.control.Button
        Button_56     matlab.ui.control.Button
        Button_57     matlab.ui.control.Button
        Button_58     matlab.ui.control.Button
        Button_59     matlab.ui.control.Button
        Button_60     matlab.ui.control.Button
        Button_61     matlab.ui.control.Button
        Button_62     matlab.ui.control.Button
        Button_63     matlab.ui.control.Button
        Button_64     matlab.ui.control.Button
        Button_65     matlab.ui.control.Button
        Button_66     matlab.ui.control.Button
        Button_start  matlab.ui.control.Button
        Button_67     matlab.ui.control.Button
        Button_69     matlab.ui.control.Button
        Button_68     matlab.ui.control.Button
        Button_70     matlab.ui.control.Button
        Button_71     matlab.ui.control.Button
        Button_72     matlab.ui.control.Button
        Label_1       matlab.ui.control.Label
        Label_2       matlab.ui.control.Label
        Label_3       matlab.ui.control.Label
        Label_4       matlab.ui.control.Label
    end

    
    properties (Access = public)
        dealPokerList = [3,3,3,3,4,4,4,4,5,5,5,5,6,6,6,6,7,7,7,7,8,8,8,8,9,9,9,9,10,10,10,10,11,11,11,11,12,12,12,12,13,13,13,13,14,14,14,14,15,15,15,15,16,17];
        % represent cards
        player1Button
        player2Button
        player3Button
        player1 = Player();
        player2 = Player();
        player3 = Player();
        controller = Controller([]);
        curPlayer
        landLord     % 用数字表示几号玩家想要地主
        readyToPlay  % 玩家选中的牌
        counter=0;   % 检测是否连续两次跳过
        
    end
    
    methods (Access = private)
        function result = Paste_Types(app, value)
            switch value
                case 11
                    result = 'J';
                case 12
                    result = 'Q';
                case 13
                    result = 'K';
                case 14
                    result = 'A';
                case 15
                    result = '2';
                case 16
                    result = 'Black Joker';
                case 17
                    result = 'Red Joker';
                otherwise
                    result = num2str(value);
            end
        end
        
        function result = Paste_Played_Card(app, value)
            result = '';
            for i = 1:length(value)
                result = [result,' ',app.Paste_Types(value(i))];
            end
        end
        
        function PastePlayerCards(app, player)
            switch player
                case app.player1
                    for i = 1:length(app.player1.cards)
                        app.player1Button(i).Text = app.Paste_Types(app.player1.cards(i));
                        app.player1Button(i).Enable = 'on';
                    end
                    for i = 1:20
                        app.player2Button(i).Text = '';
                        app.player2Button(i).Enable = 'off';
                        app.player3Button(i).Text = '';
                        app.player3Button(i).Enable = 'off';
                    end
                    app.Button_61.Enable = 'on';
                    app.Button_62.Enable = 'on';
                    app.Button_63.Enable = 'off';
                    app.Button_64.Enable = 'off';
                    app.Button_65.Enable = 'off';
                    app.Button_66.Enable = 'off';
                case app.player2
                    for i = 1:length(app.player2.cards)
                        app.player2Button(i).Text = app.Paste_Types(app.player2.cards(i));
                        app.player2Button(i).Enable = 'on';
                    end
                    for i = 1:20
                        app.player1Button(i).Text = '';
                        app.player1Button(i).Enable = 'off';
                        app.player3Button(i).Text = '';
                        app.player3Button(i).Enable = 'off';
                    end
                    app.Button_61.Enable = 'off';
                    app.Button_62.Enable = 'off';
                    app.Button_63.Enable = 'on';
                    app.Button_64.Enable = 'on';
                    app.Button_65.Enable = 'off';
                    app.Button_66.Enable = 'off';
                case app.player3
                    for i = 1:length(app.player3.cards)
                        app.player3Button(i).Text = app.Paste_Types(app.player3.cards(i));
                        app.player3Button(i).Enable = 'on';
                    end
                    for i = 1:20
                        app.player1Button(i).Text = '';
                        app.player1Button(i).Enable = 'off';
                        app.player2Button(i).Text = '';
                        app.player2Button(i).Enable = 'off';
                    end
                    app.Button_61.Enable = 'off';
                    app.Button_62.Enable = 'off';
                    app.Button_63.Enable = 'off';
                    app.Button_64.Enable = 'off';
                    app.Button_65.Enable = 'on';
                    app.Button_66.Enable = 'on';
            end
        end
        
        function Next(app)
            switch app.curPlayer
                case app.player1
                    app.curPlayer = app.player2;
                    app.PastePlayerCards(app.player2);
                case app.player2
                    app.curPlayer = app.player3;
                    app.PastePlayerCards(app.player3);
                case app.player3
                    app.curPlayer = app.player1;
                    app.PastePlayerCards(app.player1);
            end
                    
        end
        
        function Init(app)
            app.Button_67.Visible = 'on';
            app.Button_68.Visible = 'on';
            app.Button_69.Visible = 'on';
            app.Button_70.Visible = 'on';
            app.Button_71.Visible = 'on';
            app.Button_72.Visible = 'on';
            app.landLord = [];
            app.readyToPlay = [];
            app.controller.played_cards = [];
            app.player1Button = [app.Button_1,app.Button_2,app.Button_3,app.Button_4,app.Button_5,app.Button_6,app.Button_7,app.Button_8,app.Button_9,app.Button_10,app.Button_11,app.Button_12,app.Button_13,app.Button_14,app.Button_15,app.Button_16,app.Button_17,app.Button_18,app.Button_19,app.Button_20];
            app.player2Button = [app.Button_21,app.Button_22,app.Button_23,app.Button_24,app.Button_25,app.Button_26,app.Button_27,app.Button_28,app.Button_29,app.Button_30,app.Button_31,app.Button_32,app.Button_33,app.Button_34,app.Button_35,app.Button_36,app.Button_37,app.Button_38,app.Button_39,app.Button_40];
            app.player3Button = [app.Button_41,app.Button_42,app.Button_43,app.Button_44,app.Button_45,app.Button_46,app.Button_47,app.Button_48,app.Button_49,app.Button_50,app.Button_51,app.Button_52,app.Button_53,app.Button_54,app.Button_55,app.Button_56,app.Button_57,app.Button_58,app.Button_59,app.Button_60];
            app.dealPokerList = app.dealPokerList(randperm(54));  % 洗牌
            app.player1.cards = sort(app.dealPokerList(1:17));
            app.player1.playerButton = app.player1Button;
            app.player2.cards = sort(app.dealPokerList(18:34));
            app.player2.playerButton = app.player2Button;
            app.player3.cards = sort(app.dealPokerList(35:51));
            app.player3.playerButton = app.player3Button;
            app.curPlayer = app.player1;
            app.PastePlayerCards(app.player1);
            app.Button_61.Enable = 'off';
            app.Button_62.Enable = 'off';
            for i = 1:20
                app.player1Button(i).Enable = 'off';
            end
            app.Label_4.Text = '1号玩家选择抢地主';
            app.Button_68.Enable = 'off';
            app.Button_71.Enable = 'off';
            app.Button_69.Enable = 'off';
            app.Button_72.Enable = 'off';
        end
        
        function landLordOver(app)
            if isempty(app.landLord)
                app.landLord = randi(3);
            else
                app.landLord = app.landLord(randperm(length(app.landLord)));
                app.landLord = app.landLord(1);
            end
            switch app.landLord
                case 1
                    app.player1.cards(end+1:end+3) = app.dealPokerList(52:54);
                    app.player1.cards = sort(app.player1.cards);
                    app.curPlayer = app.player1;
                    app.PastePlayerCards(app.player1);
                    app.Label_1.Text = '20';  % 初始化每个人的牌数
                    app.Label_2.Text = '17';
                    app.Label_3.Text = '17';
                    app.Label_4.Text = '1号玩家成为地主';
                case 2
                    app.player2.cards(end+1:end+3) = app.dealPokerList(52:54);
                    app.player2.cards = sort(app.player2.cards);
                    app.curPlayer = app.player2;
                    app.PastePlayerCards(app.player2);
                    app.Label_1.Text = '17';  % 初始化每个人的牌数
                    app.Label_2.Text = '20';
                    app.Label_3.Text = '17';
                    app.Label_4.Text = '2号玩家成为地主';
                case 3
                    app.player3.cards(end+1:end+3) = app.dealPokerList(52:54);
                    app.player3.cards = sort(app.player3.cards);
                    app.curPlayer = app.player3;
                    app.PastePlayerCards(app.player3);
                    app.Label_1.Text = '17';  % 初始化每个人的牌数
                    app.Label_2.Text = '17';
                    app.Label_3.Text = '20';
                    app.Label_4.Text = '3号玩家成为地主';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(1);
            app.Button_1.Enable = 'off';
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(2);
            app.Button_2.Enable = 'off';
        end

        % Button pushed function: Button_start
        function Button_startPushed(app, event)
            app.Init();
        end

        % Button pushed function: Button_64
        function Button_64Pushed(app, event)
            app.counter = app.counter+1;
            if app.counter == 2
                app.controller.played_cards = [];
                app.counter = 0;
            end
            app.readyToPlay = [];
            app.Next();
        end

        % Button pushed function: Button_67
        function Button_67Pushed(app, event)
            app.landLord(end+1) = 1;
            app.Button_67.Visible = 'off';
            app.Button_70.Visible = 'off';
            app.Label_4.Text = '2号玩家选择抢地主';
            app.Button_68.Enable  = 'on';
            app.Button_71.Enable  = 'on';
            app.Next();
            app.Button_63.Enable = 'off';
            app.Button_64.Enable = 'off';
            for i = 1:20
                app.player2Button(i).Enable = 'off';
            end
        end

        % Button pushed function: Button_62
        function Button_62Pushed(app, event)
            app.counter = app.counter+1;
            if app.counter == 2
                app.controller.played_cards = [];
                app.counter = 0;
            end
            app.readyToPlay = [];
            app.Next();
        end

        % Button pushed function: Button_68
        function Button_68Pushed(app, event)
            app.landLord(end+1) = 2;
            app.Button_68.Visible = 'off';
            app.Button_71.Visible = 'off';
            app.Label_4.Text = '3号玩家选择抢地主';
            app.Button_69.Enable  = 'on';
            app.Button_72.Enable  = 'on';
            app.Next();
            app.Button_65.Enable = 'off';
            app.Button_66.Enable = 'off';
            for i = 1:20
                app.player3Button(i).Enable = 'off';
            end
        end

        % Button pushed function: Button_69
        function Button_69Pushed(app, event)
            app.landLord(end+1) = 3;
            app.Button_69.Visible = 'off';
            app.Button_72.Visible = 'off';
            app.landLordOver();
        end

        % Button pushed function: Button_70
        function Button_70Pushed(app, event)
            app.Button_70.Visible = 'off';
            app.Button_67.Visible = 'off';
            app.Label_4.Text = '2号玩家选择抢地主';
            app.Button_68.Enable  = 'on';
            app.Button_71.Enable  = 'on';
            app.Next();
            app.Button_63.Enable = 'off';
            app.Button_64.Enable = 'off';
            for i = 1:20
                app.player2Button(i).Enable = 'off';
            end
        end

        % Button pushed function: Button_71
        function Button_71Pushed(app, event)
            app.Button_71.Visible = 'off';
            app.Button_68.Visible = 'off';
            app.Label_4.Text = '3号玩家选择抢地主';
            app.Button_69.Enable  = 'on';
            app.Button_72.Enable  = 'on';
            app.Next();
            app.Button_65.Enable = 'off';
            app.Button_66.Enable = 'off';
            for i = 1:20
                app.player3Button(i).Enable = 'off';
            end
        end

        % Button pushed function: Button_72
        function Button_72Pushed(app, event)
            app.Button_72.Visible = 'off';
            app.Button_69.Visible = 'off';
            app.landLordOver();
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(3);
            app.Button_3.Enable = 'off';
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(4);
            app.Button_4.Enable = 'off';
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(5);
            app.Button_5.Enable = 'off';
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(6);
            app.Button_6.Enable = 'off';
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(7);
            app.Button_7.Enable = 'off';
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(8);
            app.Button_8.Enable = 'off';
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(9);
            app.Button_9.Enable = 'off';            
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(10);
            app.Button_10.Enable = 'off';            
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(11);
            app.Button_11.Enable = 'off';            
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(12);
            app.Button_12.Enable = 'off'; 
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(13);
            app.Button_13.Enable = 'off';            
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(14);
            app.Button_14.Enable = 'off';            
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(15);
            app.Button_15.Enable = 'off';           
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(16);
            app.Button_16.Enable = 'off';            
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(17);
            app.Button_17.Enable = 'off';            
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(18);
            app.Button_18.Enable = 'off';            
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(19);
            app.Button_19.Enable = 'off';            
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            app.readyToPlay(end+1) = app.player1.cards(20);
            app.Button_20.Enable = 'off';            
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(1);
            app.Button_21.Enable = 'off';
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(2);
            app.Button_22.Enable = 'off';
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(3);
            app.Button_23.Enable = 'off';
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(4);
            app.Button_24.Enable = 'off';
        end

        % Button pushed function: Button_25
        function Button_25Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(5);
            app.Button_25.Enable = 'off';
        end

        % Button pushed function: Button_26
        function Button_26Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(6);
            app.Button_26.Enable = 'off';            
        end

        % Button pushed function: Button_27
        function Button_27Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(7);
            app.Button_27.Enable = 'off';            
        end

        % Button pushed function: Button_28
        function Button_28Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(8);
            app.Button_28.Enable = 'off';           
        end

        % Button pushed function: Button_29
        function Button_29Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(9);
            app.Button_29.Enable = 'off';            
        end

        % Button pushed function: Button_30
        function Button_30Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(10);
            app.Button_30.Enable = 'off'; 
        end

        % Button pushed function: Button_31
        function Button_31Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(11);
            app.Button_31.Enable = 'off'; 
        end

        % Button pushed function: Button_32
        function Button_32Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(12);
            app.Button_32.Enable = 'off'; 
        end

        % Button pushed function: Button_33
        function Button_33Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(13);
            app.Button_33.Enable = 'off'; 
        end

        % Button pushed function: Button_34
        function Button_34Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(14);
            app.Button_34.Enable = 'off'; 
        end

        % Button pushed function: Button_35
        function Button_35Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(15);
            app.Button_35.Enable = 'off'; 
        end

        % Button pushed function: Button_36
        function Button_36Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(16);
            app.Button_36.Enable = 'off'; 
        end

        % Button pushed function: Button_37
        function Button_37Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(17);
            app.Button_37.Enable = 'off'; 
        end

        % Button pushed function: Button_38
        function Button_38Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(18);
            app.Button_38.Enable = 'off'; 
        end

        % Button pushed function: Button_39
        function Button_39Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(19);
            app.Button_39.Enable = 'off'; 
        end

        % Button pushed function: Button_40
        function Button_40Pushed(app, event)
            app.readyToPlay(end+1) = app.player2.cards(20);
            app.Button_40.Enable = 'off'; 
        end

        % Button pushed function: Button_41
        function Button_41Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(1);
            app.Button_41.Enable = 'off'; 
        end

        % Button pushed function: Button_42
        function Button_42Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(2);
            app.Button_42.Enable = 'off'; 
        end

        % Button pushed function: Button_43
        function Button_43Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(3);
            app.Button_43.Enable = 'off'; 
        end

        % Button pushed function: Button_44
        function Button_44Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(4);
            app.Button_44.Enable = 'off'; 
        end

        % Button pushed function: Button_45
        function Button_45Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(5);
            app.Button_45.Enable = 'off'; 
        end

        % Button pushed function: Button_46
        function Button_46Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(6);
            app.Button_46.Enable = 'off';
        end

        % Button pushed function: Button_47
        function Button_47Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(7);
            app.Button_47.Enable = 'off'; 
        end

        % Button pushed function: Button_48
        function Button_48Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(8);
            app.Button_48.Enable = 'off'; 
        end

        % Button pushed function: Button_49
        function Button_49Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(9);
            app.Button_49.Enable = 'off'; 
        end

        % Button pushed function: Button_50
        function Button_50Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(10);
            app.Button_50.Enable = 'off'; 
        end

        % Button pushed function: Button_51
        function Button_51Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(11);
            app.Button_51.Enable = 'off'; 
        end

        % Button pushed function: Button_52
        function Button_52Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(12);
            app.Button_52.Enable = 'off'; 
        end

        % Button pushed function: Button_53
        function Button_53Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(13);
            app.Button_53.Enable = 'off'; 
        end

        % Button pushed function: Button_54
        function Button_54Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(14);
            app.Button_54.Enable = 'off'; 
        end

        % Button pushed function: Button_55
        function Button_55Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(15);
            app.Button_55.Enable = 'off'; 
        end

        % Button pushed function: Button_56
        function Button_56Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(16);
            app.Button_56.Enable = 'off'; 
        end

        % Button pushed function: Button_57
        function Button_57Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(17);
            app.Button_57.Enable = 'off'; 
        end

        % Button pushed function: Button_58
        function Button_58Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(18);
            app.Button_58.Enable = 'off'; 
        end

        % Button pushed function: Button_59
        function Button_59Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(19);
            app.Button_59.Enable = 'off'; 
        end

        % Button pushed function: Button_60
        function Button_60Pushed(app, event)
            app.readyToPlay(end+1) = app.player3.cards(20);
            app.Button_60.Enable = 'off'; 
        end

        % Button pushed function: Button_61
        function Button_61Pushed(app, event)
            app.readyToPlay = sort(app.readyToPlay);
            if app.controller.isValid(app.readyToPlay)
                if app.controller.compare(app.readyToPlay)
                    app.player1.update_cards(app.readyToPlay)
                    if isempty(app.player1.cards)
                        app.Label_4.Text = '1号玩家胜利';
                    else
                        app.controller.played_cards = app.readyToPlay;
                        app.Label_4.Text = app.Paste_Played_Card(app.readyToPlay);
                        app.readyToPlay = [];
                        app.Label_1.Text = num2str(length(app.player1.cards));
                        app.Next();
                    end
                else
                    app.Label_4.Text = '出牌不符合出牌规则，无法出牌';
                    app.readyToPlay = [];
                    app.PastePlayerCards(app.player1);
                end
            else
                app.Label_4.Text = '出牌不符合出牌规则，无法出牌';
                app.readyToPlay = [];
                app.PastePlayerCards(app.player1);
            end
        end

        % Button pushed function: Button_63
        function Button_63Pushed(app, event)
            app.readyToPlay = sort(app.readyToPlay);
            if app.controller.isValid(app.readyToPlay)
                if app.controller.compare(app.readyToPlay)
                    app.player2.update_cards(app.readyToPlay)
                    if isempty(app.player2.cards)
                        app.Label_4.Text = '2号玩家胜利';
                    else
                        app.controller.played_cards = app.readyToPlay;
                        app.Label_4.Text = app.Paste_Played_Card(app.readyToPlay);
                        app.readyToPlay = [];
                        app.Label_2.Text = num2str(length(app.player2.cards));
                        app.Next();
                    end
                else
                    app.Label_4.Text = '出牌不符合出牌规则，无法出牌';
                    app.readyToPlay = [];
                    app.PastePlayerCards(app.player2);
                end
            else
                app.Label_4.Text = '出牌不符合出牌规则，无法出牌';
                app.readyToPlay = [];
                app.PastePlayerCards(app.player2);
            end
        end

        % Button pushed function: Button_65
        function Button_65Pushed(app, event)
            app.readyToPlay = sort(app.readyToPlay);
            if app.controller.isValid(app.readyToPlay)
                if app.controller.compare(app.readyToPlay)
                    app.player3.update_cards(app.readyToPlay)
                    if isempty(app.player3.cards)
                        app.Label_4.Text = '3号玩家胜利';
                    else
                        app.controller.played_cards = app.readyToPlay;
                        app.Label_4.Text = app.Paste_Played_Card(app.readyToPlay);
                        app.readyToPlay = [];
                        app.Label_3.Text = num2str(length(app.player3.cards));
                        app.Next();
                    end
                else
                    app.Label_4.Text = '出牌不符合出牌规则，无法出牌';
                    app.readyToPlay = [];
                    app.PastePlayerCards(app.player3);
                end
            else
                app.Label_4.Text = '出牌不符合出牌规则，无法出牌';
                app.readyToPlay = [];
                app.PastePlayerCards(app.player3);
            end
        end

        % Button pushed function: Button_66
        function Button_66Pushed(app, event)
            app.counter = app.counter+1;
            if app.counter == 2
                app.controller.played_cards = [];
                app.counter = 0;
            end
            app.readyToPlay = [];
            app.Next();
        end

        % Callback function
        function Button_73Pushed(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '';
            app.Button_22 = uibutton(app.UIFigure, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '';
            app.Button_23 = uibutton(app.UIFigure, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '';
            app.Button_24 = uibutton(app.UIFigure, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Text = '';
            app.Button_25 = uibutton(app.UIFigure, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_25Pushed, true);
            app.Button_25.Text = '';
            app.Button_26 = uibutton(app.UIFigure, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @Button_26Pushed, true);
            app.Button_26.Text = '';
            app.Button_27 = uibutton(app.UIFigure, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @Button_27Pushed, true);
            app.Button_27.Text = '';
            app.Button_28 = uibutton(app.UIFigure, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @Button_28Pushed, true);
            app.Button_28.Text = '';
            app.Button_29 = uibutton(app.UIFigure, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @Button_29Pushed, true);
            app.Button_29.Text = '';
            app.Button_30 = uibutton(app.UIFigure, 'push');
            app.Button_30.ButtonPushedFcn = createCallbackFcn(app, @Button_30Pushed, true);
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.UIFigure, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @Button_31Pushed, true);
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.UIFigure, 'push');
            app.Button_32.ButtonPushedFcn = createCallbackFcn(app, @Button_32Pushed, true);
            app.Button_32.Text = '';
            app.Button_33 = uibutton(app.UIFigure, 'push');
            app.Button_33.ButtonPushedFcn = createCallbackFcn(app, @Button_33Pushed, true);
            app.Button_33.Text = '';
            app.Button_34 = uibutton(app.UIFigure, 'push');
            app.Button_34.ButtonPushedFcn = createCallbackFcn(app, @Button_34Pushed, true);
            app.Button_34.Text = '';
            app.Button_35 = uibutton(app.UIFigure, 'push');
            app.Button_35.ButtonPushedFcn = createCallbackFcn(app, @Button_35Pushed, true);
            app.Button_35.Text = '';
            app.Button_36 = uibutton(app.UIFigure, 'push');
            app.Button_36.ButtonPushedFcn = createCallbackFcn(app, @Button_36Pushed, true);
            app.Button_36.Text = '';
            app.Button_37 = uibutton(app.UIFigure, 'push');
            app.Button_37.ButtonPushedFcn = createCallbackFcn(app, @Button_37Pushed, true);
            app.Button_37.Text = '';
            app.Button_38 = uibutton(app.UIFigure, 'push');
            app.Button_38.ButtonPushedFcn = createCallbackFcn(app, @Button_38Pushed, true);
            app.Button_38.Text = '';
            app.Button_39 = uibutton(app.UIFigure, 'push');
            app.Button_39.ButtonPushedFcn = createCallbackFcn(app, @Button_39Pushed, true);
            app.Button_39.Text = '';
            app.Button_40 = uibutton(app.UIFigure, 'push');
            app.Button_40.ButtonPushedFcn = createCallbackFcn(app, @Button_40Pushed, true);
            app.Button_40.Text = '';
            app.Button_41 = uibutton(app.UIFigure, 'push');
            app.Button_41.ButtonPushedFcn = createCallbackFcn(app, @Button_41Pushed, true);
            app.Button_41.Text = '';
            app.Button_42 = uibutton(app.UIFigure, 'push');
            app.Button_42.ButtonPushedFcn = createCallbackFcn(app, @Button_42Pushed, true);
            app.Button_42.Text = '';
            app.Button_43 = uibutton(app.UIFigure, 'push');
            app.Button_43.ButtonPushedFcn = createCallbackFcn(app, @Button_43Pushed, true);
            app.Button_43.Text = '';
            app.Button_44 = uibutton(app.UIFigure, 'push');
            app.Button_44.ButtonPushedFcn = createCallbackFcn(app, @Button_44Pushed, true);
            app.Button_44.Text = '';
            app.Button_45 = uibutton(app.UIFigure, 'push');
            app.Button_45.ButtonPushedFcn = createCallbackFcn(app, @Button_45Pushed, true);
            app.Button_45.Text = '';
            app.Button_46 = uibutton(app.UIFigure, 'push');
            app.Button_46.ButtonPushedFcn = createCallbackFcn(app, @Button_46Pushed, true);
            app.Button_46.Text = '';
            app.Button_47 = uibutton(app.UIFigure, 'push');
            app.Button_47.ButtonPushedFcn = createCallbackFcn(app, @Button_47Pushed, true);
            app.Button_47.Text = '';
            app.Button_48 = uibutton(app.UIFigure, 'push');
            app.Button_48.ButtonPushedFcn = createCallbackFcn(app, @Button_48Pushed, true);
            app.Button_48.Text = '';
            app.Button_49 = uibutton(app.UIFigure, 'push');
            app.Button_49.ButtonPushedFcn = createCallbackFcn(app, @Button_49Pushed, true);
            app.Button_49.Text = '';
            app.Button_50 = uibutton(app.UIFigure, 'push');
            app.Button_50.ButtonPushedFcn = createCallbackFcn(app, @Button_50Pushed, true);
            app.Button_50.Text = '';
            app.Button_51 = uibutton(app.UIFigure, 'push');
            app.Button_51.ButtonPushedFcn = createCallbackFcn(app, @Button_51Pushed, true);
            app.Button_51.Text = '';
            app.Button_52 = uibutton(app.UIFigure, 'push');
            app.Button_52.ButtonPushedFcn = createCallbackFcn(app, @Button_52Pushed, true);
            app.Button_52.Text = '';
            app.Button_53 = uibutton(app.UIFigure, 'push');
            app.Button_53.ButtonPushedFcn = createCallbackFcn(app, @Button_53Pushed, true);
            app.Button_53.Text = '';
            app.Button_54 = uibutton(app.UIFigure, 'push');
            app.Button_54.ButtonPushedFcn = createCallbackFcn(app, @Button_54Pushed, true);
            app.Button_54.Text = '';
            app.Button_55 = uibutton(app.UIFigure, 'push');
            app.Button_55.ButtonPushedFcn = createCallbackFcn(app, @Button_55Pushed, true);
            app.Button_55.Text = '';
            app.Button_56 = uibutton(app.UIFigure, 'push');
            app.Button_56.ButtonPushedFcn = createCallbackFcn(app, @Button_56Pushed, true);
            app.Button_56.Text = '';
            app.Button_57 = uibutton(app.UIFigure, 'push');
            app.Button_57.ButtonPushedFcn = createCallbackFcn(app, @Button_57Pushed, true);
            app.Button_57.Text = '';
            app.Button_58 = uibutton(app.UIFigure, 'push');
            app.Button_58.ButtonPushedFcn = createCallbackFcn(app, @Button_58Pushed, true);
            app.Button_58.Text = '';
            app.Button_59 = uibutton(app.UIFigure, 'push');
            app.Button_59.ButtonPushedFcn = createCallbackFcn(app, @Button_59Pushed, true);
            app.Button_59.Text = '';
            app.Button_60 = uibutton(app.UIFigure, 'push');
            app.Button_60.ButtonPushedFcn = createCallbackFcn(app, @Button_60Pushed, true);
            app.Button_60.Text = '';
            app.Button_61 = uibutton(app.UIFigure, 'push');
            app.Button_61.ButtonPushedFcn = createCallbackFcn(app, @Button_61Pushed, true);
            app.Button_61.Text = '出牌';
            app.Button_62 = uibutton(app.UIFigure, 'push');
            app.Button_62.ButtonPushedFcn = createCallbackFcn(app, @Button_62Pushed, true);
            app.Button_62.Text = '跳过';
            app.Button_63 = uibutton(app.UIFigure, 'push');
            app.Button_63.ButtonPushedFcn = createCallbackFcn(app, @Button_63Pushed, true);
            app.Button_63.Text = '出牌';
            app.Button_64 = uibutton(app.UIFigure, 'push');
            app.Button_64.ButtonPushedFcn = createCallbackFcn(app, @Button_64Pushed, true);
            app.Button_64.Text = '跳过';
            app.Button_65 = uibutton(app.UIFigure, 'push');
            app.Button_65.ButtonPushedFcn = createCallbackFcn(app, @Button_65Pushed, true);
            app.Button_65.Text = '出牌';
            app.Button_66 = uibutton(app.UIFigure, 'push');
            app.Button_66.ButtonPushedFcn = createCallbackFcn(app, @Button_66Pushed, true);
            app.Button_66.Text = '跳过';
            app.Button_start = uibutton(app.UIFigure, 'push');
            app.Button_start.ButtonPushedFcn = createCallbackFcn(app, @Button_startPushed, true);
            app.Button_start.Text = '开始';
            app.Button_67 = uibutton(app.UIFigure, 'push');
            app.Button_67.ButtonPushedFcn = createCallbackFcn(app, @Button_67Pushed, true);
            app.Button_67.Text = '抢地主';
            app.Button_69 = uibutton(app.UIFigure, 'push');
            app.Button_69.ButtonPushedFcn = createCallbackFcn(app, @Button_69Pushed, true);
            app.Button_69.Text = '抢地主';
            app.Button_68 = uibutton(app.UIFigure, 'push');
            app.Button_68.ButtonPushedFcn = createCallbackFcn(app, @Button_68Pushed, true);
            app.Button_68.Text = '抢地主';
            app.Button_70 = uibutton(app.UIFigure, 'push');
            app.Button_70.ButtonPushedFcn = createCallbackFcn(app, @Button_70Pushed, true);
            app.Button_70.Text = '不叫';
            app.Button_71 = uibutton(app.UIFigure, 'push');
            app.Button_71.ButtonPushedFcn = createCallbackFcn(app, @Button_71Pushed, true);
            app.Button_71.Text = '不叫';
            app.Button_72 = uibutton(app.UIFigure, 'push');
            app.Button_72.ButtonPushedFcn = createCallbackFcn(app, @Button_72Pushed, true);
            app.Button_72.Text = '不叫';
            app.Label_1 = uilabel(app.UIFigure);
            app.Label_1.Text = '牌数';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '牌数';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '牌数';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '出牌';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Poker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end